classdef base_station < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TextArea                matlab.ui.control.TextArea
        xmlfileEditField        matlab.ui.control.EditField
        xmlfileEditFieldLabel   matlab.ui.control.Label
        DISCONNECTButton        matlab.ui.control.Button
        CONNECTButton           matlab.ui.control.Button
        BaudRateEditField       matlab.ui.control.NumericEditField
        BaudRateEditFieldLabel  matlab.ui.control.Label
        COMPortEditField        matlab.ui.control.EditField
        COMPortEditFieldLabel   matlab.ui.control.Label
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    properties (Access = public)
        serial
        dialect
        mavlink
        timer_rx
        timer_heartbeat

        pool
        queue_to_worker
        queue_to_gui
        queue_debug
        worker

        timer_disp
        tx_bytes_accum = uint64(0);
        stats = struct( ...
            'status', 0, ...                   % reserved
            'radio', struct( ...               % latest RADIO_STATUS snapshot
                'rssi', NaN, ...
                'remrssi', NaN, ...
                'noise', NaN, ...
                'remnoise', NaN, ...
                'txbuf', NaN, ...
                'rxerrors', NaN, ...
                'fixed', NaN, ...
                'snr', NaN, ...                % computed: rssi - noise
                'rem_snr', NaN, ...            % computed: remrssi - remnoise
                'ts', NaT ...                  % timestamp of last update
            ), ...
            'uart', struct( ...
                'baud', NaN, ...        % current baudrate
                'bits_per_byte', NaN, ...% start + data + stop (+parity)
                'util_rx', NaN, ...     % %
                'util_tx', NaN, ...     % %
                'util_total', NaN, ...  % %
                'rx_speed', NaN, ...      % bytes/s
                'tx_speed', NaN, ...      % bytes/s
                'active', false ...
            ) ...
        );
    end
            
    methods (Access = private)
        function bpb = get_bits_per_byte(app)
            % 1 start + DataBits + StopBits + (parity ? 1 : 0)
            try
                dataBits = app.serial.DataBits;      % zwykle 8
                stopBits = app.serial.StopBits;      % 1, 1.5, 2 (zaokrąglimy w górę)
                parityOn = ~strcmpi(app.serial.Parity, "none");
                bpb = 1 + double(dataBits) + ceil(double(stopBits)) + double(parityOn);
            catch
                bpb = 10; % domyślnie 8N1
            end
        end
        function refresh_uart_params(app, baud)
            app.stats.uart.baud = double(baud);
            app.stats.uart.bits_per_byte = app.get_bits_per_byte();
        end
        function writeSerial(app, data, precision)
            if nargin < 3, precision = "uint8"; end
            write(app.serial, data, precision);
            % zlicz ile bajtów poszło
            app.tx_bytes_accum = app.tx_bytes_accum + uint64(numel(typecast(cast(data, 'uint8'), 'uint8')));
        end

        function sendHeartbeat(app)
            try
                hbMsg = createmsg(app.dialect, "HEARTBEAT");
                % GCS identity and basic mode/state for MAVLink v1.0
                hbMsg.Payload.type = uint8(6);              % MAV_TYPE_GCS (6)
                hbMsg.Payload.autopilot = uint8(8);         % MAV_AUTOPILOT_INVALID (8)
                hbMsg.Payload.base_mode = uint8(0);         % No base mode flags set
                hbMsg.Payload.system_status = uint8(0);     % MAV_STATE_UNINIT (0)
                hbMsg.Payload.custom_mode = uint32(0);      % No custom mode
                % hbMsg.Payload.mavlink_version = uint8(3); % Not writable by user
                hbMsg.SystemID = uint8(255);                % Typical GCS system ID
                hbMsg.ComponentID = uint8(190);             % Typical GCS component ID
                buffer = serializemsg(app.mavlink, hbMsg);
                app.writeSerial(buffer, "uint8");
            catch e
                disp("Heartbeat send error: " + e.message)
            end
        end

        function rx(app)
            % RX read
            data = uint8([]);
            if app.serial.NumBytesAvailable > 0
                data = read(app.serial, app.serial.NumBytesAvailable, "uint8");
                data = uint8(data);
                send(app.queue_to_worker, data);
            end
        
            % Sample period = timer_rx.Period
            Ts = app.timer_rx.Period;
        
            % RX speed (bytes/s)
            rx_bytes = length(data);
            app.stats.uart.rx_speed = app.avg_speed_window(rx_bytes, Ts, "rx");
        
            % TX speed (bytes/s)
            tx_bytes = double(app.tx_bytes_accum);
            app.tx_bytes_accum = uint64(0); % reset po próbkowaniu
            app.stats.uart.tx_speed = app.avg_speed_window(tx_bytes, Ts, "tx");
        
            % Port utilization
            baud = app.stats.uart.baud;
            bpb  = app.stats.uart.bits_per_byte;
            if ~(isnan(baud) || baud <= 0)
                % bits/s użyte: bytes/s * bits/byte
                util_rx = (app.stats.uart.rx_speed * bpb) / baud;
                util_tx = (app.stats.uart.tx_speed * bpb) / baud;
                app.stats.uart.util_rx    = 100 * util_rx;
                app.stats.uart.util_tx    = 100 * util_tx;
                app.stats.uart.util_total = 100 * (util_rx + util_tx); % przy half‑duplex to i tak max~100
            else
                app.stats.uart.util_rx = NaN;
                app.stats.uart.util_tx = NaN;
                app.stats.uart.util_total = NaN;
            end
        end
        
        function speed = avg_speed_window(app, bytes, period, tag)
            % Keeps a per-tag sliding window average of byte rate.
            % 'tag' identifies separate buffers for RX and TX.
            % Uses a 1-second window split into 1/period samples.
            window_time = 1; % [s]
            samples = max(1, round(window_time / period));
            persistent map;
            if isempty(map), map = containers.Map(); end
            if ~isKey(map, tag)
                map(tag) = struct( ...
                    'buffer', zeros(1, samples), ...
                    'index', 0, ...
                    'count', 0, ...
                    'sum_bytes', 0, ...
                    'samples', samples);
            end
            st = map(tag);
            % Realloc if period changed => samples changed
            if st.samples ~= samples
                st.buffer = zeros(1, samples);
                st.index = 0; st.count = 0; st.sum_bytes = 0; st.samples = samples;
            end
            st.index = mod(st.index, samples) + 1;
            st.sum_bytes = st.sum_bytes - st.buffer(st.index) + bytes;
            st.buffer(st.index) = bytes;
            st.count = min(st.count + 1, samples);
            map(tag) = st;
            speed = st.sum_bytes / (st.count * period); % bytes/s
        end


% --- MAIN DISPLAY UPDATE ---
function disp_update_stats(app)
    % lokalny helper tylko na potrzeby tego bloku:
    function out = ternNum(val, fmt)
        if isnan(val)
            out = "--";
        else
            out = sprintf(fmt, val);
        end
    end
    function out = ternNumFlag(flag, onText, offText)
        if flag
            out = onText;
        else
            out = offText;
        end
    end
    function s = staleSuffix(ts)
        if ~isnat(ts)
            age = seconds(datetime('now') - ts);
            s = sprintf('(age: %.1fs)', age);
        else
            s = '';
        end
    end
    % --- Uart utilization ---
    u = app.stats.uart;
    lines_util = {
        sprintf("Serial port    %s", ternNumFlag(u.active, 'active', 'inactive'))
        sprintf("  RX speed:\t%s",      ternNum(u.rx_speed,   "%.1f B/s"))
        sprintf("  TX speed:\t%s",      ternNum(u.tx_speed,   "%.1f B/s"))
        sprintf("  RX load: \t%s",      ternNum(u.util_rx,    "%.1f%%"))
        sprintf("  TX load:\t\t%s",     ternNum(u.util_tx,    "%.1f%%"))
        sprintf("  Total load:\t%s",    ternNum(u.util_total, "%.1f%%"))
        sprintf("  Baud rate:\t%s",     ternNum(u.baud,       "%g"))
        sprintf("  Bits per byte:\t%s", ternNum(u.bits_per_byte, "%g"))
    };
    % --- RADIO STATUS ---
    r = app.stats.radio;
    lines_radio = {
        sprintf("RADIO LINK\t%s", staleSuffix(r.ts))
        sprintf("  RSSI (local):\t\t%s",   ternNum(r.rssi,  "%.0f dBm"))
        sprintf("  RSSI (remote):\t%s",    ternNum(r.remrssi,"%.0f dBm"))
        sprintf("  Noise (local):\t\t%s",  ternNum(r.noise,  "%.0f dBm"))
        sprintf("  Noise (remote):\t%s",   ternNum(r.remnoise,"%.0f dBm"))
        sprintf("  SNR (local):\t\t%s",    ternNum(r.snr,    "%.0f dB"))
        sprintf("  SNR (remote):\t%s",     ternNum(r.rem_snr,"%.0f dB"))
        sprintf("  TX buffer free:\t%s",   ternNum(r.txbuf,  "%.0f%%"))
        sprintf("  RX errors (cum):\t%s",  ternNum(r.rxerrors,"%.0f"))
        sprintf("  Fixed (FEC, cum):\t%s", ternNum(r.fixed,  "%.0f"))
    };
    % --- Compose and show ---
    app.TextArea.Value = [
        cellstr(lines_util);
        cellstr(lines_radio)
    ];
end
function resetStats(app)
    % --- RADIO ---
    app.stats.radio.rssi     = NaN;
    app.stats.radio.remrssi  = NaN;
    app.stats.radio.noise    = NaN;
    app.stats.radio.remnoise = NaN;
    app.stats.radio.txbuf    = NaN;
    app.stats.radio.rxerrors = NaN;
    app.stats.radio.fixed    = NaN;
    app.stats.radio.snr      = NaN;
    app.stats.radio.rem_snr  = NaN;
    % --- UART ---
    app.stats.uart.baud          = NaN;
    app.stats.uart.bits_per_byte = NaN;
    app.stats.uart.util_rx       = NaN;
    app.stats.uart.util_tx       = NaN;
    app.stats.uart.util_total    = NaN;
    app.stats.uart.rx_speed      = NaN;
    app.stats.uart.tx_speed      = NaN;
end
                
        function onMsgs(app, msgs)
            % Handle parsed messages arriving from the worker.
            function v = toDbl(s, name)
                if isfield(s, name) && ~isempty(s.(name))
                    v = double(s.(name));
                else
                    v = NaN;
                end
            end
            for i = 1:numel(msgs)
                s = msgs{i};
                if ~isstruct(s), continue; end
                switch s.msgid
                    case 0 % HEARTBEAT
                        % Example fields: s.type, s.base_mode, s.system_status
                    case 109 % RADIO_STATUS
                        % Store full snapshot and compute SNRs
                        app.stats.radio.rssi     = (toDbl(s, 'rssi') / 1.9 ) - 127; % dBm
                        app.stats.radio.remrssi  = (toDbl(s, 'remrssi') / 1.9 ) - 127; % dBm
                        app.stats.radio.noise    = (toDbl(s, 'noise') / 1.9 ) - 127; % dBm
                        app.stats.radio.remnoise = (toDbl(s, 'remnoise') / 1.9 ) - 127; % dBm
                        app.stats.radio.txbuf    = toDbl(s, 'txbuf');
                        app.stats.radio.rxerrors = toDbl(s, 'rxerrors');
                        app.stats.radio.fixed    = toDbl(s, 'fixed');
                        if ~isnan(app.stats.radio.rssi) && ~isnan(app.stats.radio.noise)
                            app.stats.radio.snr = app.stats.radio.rssi - app.stats.radio.noise;
                        else
                            app.stats.radio.snr = NaN;
                        end
                        if ~isnan(app.stats.radio.remrssi) && ~isnan(app.stats.radio.remnoise)
                            app.stats.radio.rem_snr = app.stats.radio.remrssi - app.stats.radio.remnoise;
                        else
                            app.stats.radio.rem_snr = NaN;
                        end
                        app.stats.radio.ts = datetime('now');
                    case 200 % GENERIC_CAN_FRAME
                        
                    case 201 % DEBUG_FRAME

                    otherwise
                        % Fallback: access s.payload_raw (uint8) for unhandled messages
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.timer_disp = timer('ExecutionMode', 'fixedSpacing', ...
                                      'Period', 1/10, ... % 10 hz
                                      'TimerFcn', @(~,~) app.disp_update_stats());
            start(app.timer_disp);

        end

        % Button pushed function: CONNECTButton
        function connectSerial(app, event)
            clc
            port = char(app.COMPortEditField.Value);
            baud = double(app.BaudRateEditField.Value);
            xmlPath = char(app.xmlfileEditField.Value);
            
            % 1) Dialect + mavlink
            try
                app.dialect = mavlinkdialect(xmlPath, 1);
                app.mavlink = mavlinkio(app.dialect);
                disp(['Dialect ', xmlPath, ' loaded']);
            catch e
                app.mavlink = [];
                uialert(app.UIFigure, ['Error loading dialect: ', e.message], 'MAVLink Error');
                return;
            end
    
            % 2) Serial
            try
                app.serial = serialport(port, baud);
                app.refresh_uart_params(baud);
                app.stats.uart.active = true;   % <-- UŻYWAJ "active" W KAŻDYM MIEJSCU
                disp(['Connected to ', port, ' at ', num2str(baud), ' baud']);
            catch e
                app.stats.uart.active = false;
                uialert(app.UIFigure, ['Error connecting: ', e.message], 'Connection Error');
                return; % No port -> do not start nothig further
            end
    
            % 3) Pool/queues/worker
            try
                disp("Starting the thread worker...")
                if ~isempty(gcp('nocreate'))
                    delete(gcp('nocreate'))
                end
                app.pool = parpool('Processes',1);
                app.queue_to_worker = parallel.pool.PollableDataQueue(Destination="any");
                app.queue_to_gui = parallel.pool.DataQueue;
                app.queue_debug = parallel.pool.DataQueue;
        
                afterEach(app.queue_to_gui, @(msgs) app.onMsgs(msgs));
                afterEach(app.queue_debug, @(data) disp("@@" + string(data)));
        
                app.worker = parfeval(app.pool, @rx_worker, 0, app.queue_to_worker, app.queue_to_gui, app.queue_debug);
                disp('Started a thread worker for heavy tasks');
            catch e
                uialert(app.UIFigure, ['Error starting a worker: ', e.message], 'Thread Error');
                return;
            end
    
            % 4) RX timer
            try
                app.timer_rx = timer('ExecutionMode', 'fixedSpacing', ...
                          'Period', 0.5, ...
                          'TimerFcn', @(~,~) app.rx());
                start(app.timer_rx);
            catch e
                uialert(app.UIFigure, ['Error with a timer: ', e.message], 'Timer Error');
            end
        
            % 5) Heartbeat timer
            try
                app.timer_heartbeat = timer('ExecutionMode', 'fixedSpacing', ...
                                             'Period', 1.0, ...
                                             'TimerFcn', @(~,~) app.sendHeartbeat());
                start(app.timer_heartbeat);
                disp("Heartbeat timer started");
            catch e
                uialert(app.UIFigure, ['Error starting heartbeat timer: ', e.message], 'Timer Error');
            end
        end

        % Button pushed function: DISCONNECTButton
        function disconnectSerial(app, event)

            % 1) Timers
            try
                stop(app.timer_rx)
                delete(app.timer_rx)
            catch e
                uialert(app.UIFigure, ['Error stoping timer: ', e.message], 'Thread Error');
            end
            try
                stop(app.timer_heartbeat);
                delete(app.timer_heartbeat);
                disp("Heartbeat timer stopped");
            catch e
                uialert(app.UIFigure, ['Error stoping timer: ', e.message], 'Thread Error');
            end
            
            % 2) Serial
            try
                if ~isempty(app.serial) && isvalid(app.serial)
                    delete(app.serial);
                end
                app.stats.uart.active = false;
                app.resetStats()
                disp(['Serial port ', char(app.COMPortEditField.Value), ' disconnected']);
            catch e
                app.stats.uart.active = false;
                app.resetStats()
                uialert(app.UIFigure, ['Error disconnecting: ', e.message], 'Connection Error');
            end
            
            % 3) Workers / pool
            try
                send(app.queue_to_worker, '__SHUTDOWN__');
                disp("Shutdown commanded to worker");
                if ~isempty(app.worker) && isvalid(app.worker)
                    % max 3 s na odeberanie i zamkniecie sie
                    try wait(app.worker, 3); catch, end 
                    try cancel(app.worker); catch, end 
                    delete(app.worker);
                    app.worker = [];
                end
            catch e
                uialert(app.UIFigure, ['Error stopping worker: ', e.message], 'Thread Error');
            end
            try
                if ~isempty(gcp('nocreate'))
                    delete(gcp('nocreate'))
                end
                if ~isempty(app.pool)
                    delete(app.pool);
                    app.pool = [];
                end
                disp('Stopped a thread pool');
            catch e
                uialert(app.UIFigure, ['Error deleting pool: ', e.message], 'Thread Error');
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.COMPortEditFieldLabel = uilabel(app.UIFigure);
            app.COMPortEditFieldLabel.Text = 'COM Port';
            app.COMPortEditField = uieditfield(app.UIFigure, 'text');
            app.COMPortEditField.Placeholder = 'COM';
            app.COMPortEditField.Value = 'COM17';
            app.BaudRateEditFieldLabel = uilabel(app.UIFigure);
            app.BaudRateEditFieldLabel.Text = 'BaudRate';
            app.BaudRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.BaudRateEditField.Limits = [0 Inf];
            app.BaudRateEditField.ValueDisplayFormat = '%d';
            app.BaudRateEditField.Value = 115200;
            app.CONNECTButton = uibutton(app.UIFigure, 'push');
            app.CONNECTButton.ButtonPushedFcn = createCallbackFcn(app, @connectSerial, true);
            app.CONNECTButton.Text = 'CONNECT';
            app.DISCONNECTButton = uibutton(app.UIFigure, 'push');
            app.DISCONNECTButton.ButtonPushedFcn = createCallbackFcn(app, @disconnectSerial, true);
            app.DISCONNECTButton.Text = 'DISCONNECT';
            app.xmlfileEditFieldLabel = uilabel(app.UIFigure);
            app.xmlfileEditFieldLabel.Text = '.xml file';
            app.xmlfileEditField = uieditfield(app.UIFigure, 'text');
            app.xmlfileEditField.Placeholder = './mavmc.xml';
            app.xmlfileEditField.Value = '/mavmc.xml';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Placeholder = 'Connection stats here';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = base_station

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end